classdef QuailKit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        FileMenu           matlab.ui.container.Menu
        SpectrogramsPanel  matlab.ui.container.Panel
        ModeDropDownLabel  matlab.ui.control.Label
        ModeDropDown       matlab.ui.control.DropDown
        Panel              matlab.ui.container.Panel
        MapCheckBox        matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function OnlineButtonPushed(app, event)
          
  
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)

            if app.ModeDropDown.Value == "Online"
                app.SpectrogramsPanel.AutoResizeChildren = 'off';
                ax1 = subplot(4,1,1,'Parent',app.SpectrogramsPanel);
                ax2 = subplot(4,1,2,'Parent',app.SpectrogramsPanel);
                ax3 = subplot(4,1,3,'Parent',app.SpectrogramsPanel);
                ax4 = subplot(4,1,4,'Parent',app.SpectrogramsPanel);
                t = 0:0.001:2;
                x = chirp(t,100,1,500,'quadratic');
                [f ,t,p] = spectrogram(x,100,80,100,1000,'xaxis');
                imagesc(ax1,abs(f))
                imagesc(ax2,abs(f))
                imagesc(ax3,abs(f))
                imagesc(ax4,abs(f))
                xlim(ax1,[10 20])
            end
        end

        % Size changed function: SpectrogramsPanel
        function SpectrogramsPanelSizeChanged(app, event)
            position = app.SpectrogramsPanel.Position;
        end

        % Value changed function: MapCheckBox
        function MapCheckBoxValueChanged(app, event)
         if app.ModeDropDown.Value == "Online"
            if app.MapCheckBox.Value == 1
                x= 1:0.01:4*pi;
                gx = geoaxes('parent',app.Panel)
                geoplot(gx,33.35,-101.012,'o','MarkerSize',5,'MarkerFaceColor','b');
                geobasemap(gx, 'satellite')
            else
                delete(app.Panel.Children)
            end
        end
        end

        % Size changed function: Panel
        function PanelSizeChanged(app, event)
            position = app.Panel.Position;
            
        end

        % Menu selected function: FileMenu
        function FileMenuSelected(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.MenuSelectedFcn = createCallbackFcn(app, @FileMenuSelected, true);
            app.FileMenu.Text = 'File';
            app.SpectrogramsPanel = uipanel(app.UIFigure);
            app.SpectrogramsPanel.Title = 'Spectrograms';
            app.SpectrogramsPanel.SizeChangedFcn = createCallbackFcn(app, @SpectrogramsPanelSizeChanged, true);
            app.ModeDropDownLabel = uilabel(app.UIFigure);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.UIFigure);
            app.ModeDropDown.Items = {'Offline', 'Online'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Offline';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.SizeChangedFcn = createCallbackFcn(app, @PanelSizeChanged, true);
            app.MapCheckBox = uicheckbox(app.UIFigure);
            app.MapCheckBox.ValueChangedFcn = createCallbackFcn(app, @MapCheckBoxValueChanged, true);
            app.MapCheckBox.Text = ' Map';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QuailKit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end